classdef NSPstreamerGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MainUIFigure                 matlab.ui.Figure
        ProjectMenu                  matlab.ui.container.Menu
        ENABLEMenu                   matlab.ui.container.Menu
        YCORMenu                     matlab.ui.container.Menu
        STROKEMONMenu                matlab.ui.container.Menu
        CAROMONMenu                  matlab.ui.container.Menu
        CARLMenu                     matlab.ui.container.Menu
        StatusPanel                  matlab.ui.container.Panel
        BoluspassageGauge            matlab.ui.control.LinearGauge
        BoluspassageGaugeLabel       matlab.ui.control.Label
        StatusHTML                   matlab.ui.control.HTML
        StatusHTMLLabel              matlab.ui.control.Label
        LogTextArea                  matlab.ui.control.TextArea
        LogTextAreaLabel             matlab.ui.control.Label
        StO2recordingLamp            matlab.ui.control.Lamp
        StO2recordingLampLabel       matlab.ui.control.Label
        IncomingdataLamp             matlab.ui.control.Lamp
        IncomingdataLampLabel        matlab.ui.control.Label
        ControlsPanel                matlab.ui.container.Panel
        RecordStO2CheckBox           matlab.ui.control.CheckBox
        StreamDataSwitch             matlab.ui.control.Switch
        StreamDataSwitchLabel        matlab.ui.control.Label
        MeasurementInformationPanel  matlab.ui.container.Panel
        SIDEditField                 matlab.ui.control.EditField
        SIDEditFieldLabel            matlab.ui.control.Label
        PIDEditField                 matlab.ui.control.EditField
        PIDEditFieldLabel            matlab.ui.control.Label
    end

    
    % TODO: 
    % - Fix time stamp in file name of perfusion files (congruent to
    %   post-hoc function)
    %       '-> fixed with: "datestr(datetime - diff(seconds(currentDATA(1,[find(iBolus,1,'first') end])))..." ?!
    %                 (was: "datestr(datetime - diff(seconds(currentDATA(2,[find(iBolus,1,'first') end])))...")
    % - Adapt ticks of bolus progress gauge (ugly with if-else clause...)
    % - Menu to select Project name
    % - Implement accelerometer streaming
    
    % NOTES:
    % LSL streams:
    % - Trigger and Acc streams are created once configuration is loaded.
    %   Data stream is not created until recording has been started.
    % - Acc stream is created only if use_accelerometers in config is true.
    %   No data is transmitted if no accelerometers are found (eg if
    %   detectors were not connected when Aurora started!)
    
    
    properties (Access = public)
        PATHS % structure of required paths
%         lsl_cfg % structure w/ data required for lsl
        cnsts % NIRS related constants
        lsl_inlets = struct('nirs',[], 'trigger',[], 'acc',[]);
        lsl_InlNames = struct('nirs','Aurora', 'trigger','NIRStarTriggers','acc','Aurora_accelerometer');
        
        capture_watchdog_timer
        captureMeas_timer
        
        logFile;
    end
    
    properties (Access = private)
        appPath % path to mlapp file
        lsl_lib % lsl library handle, setup at startup
        bootDatetime;
    end
    
    properties (Dependent)
        PID % Patient ID
        SID % Session ID
        capture_active % true when Streaming switch in on
        calc_StO2 % true when StO2 checkbox is on
        selectedProject
    end
    
    properties (Constant)
        capture_watchdog_timerProps = {...
            'Name',                 'capture_watchdog_timer', ...
            'ExecutionMode',        'fixedSpacing', ...
            'Period',               1, ...
            'busyMode',             'drop'};
        captureMeas_timerProps = {...
            'Name',                 'captureMeas_timer', ...
            'ExecutionMode',        'fixedRate', ...
            'Period',               1, ...
            'busyMode',             'drop'};
        
        bolusPreTrgNum = 48;
        bolusTrgNum = [49 50 51];
        bolusInitTime = 10; % seconds - Defined by Bolus Control GUI!
        bolusPreLength = 290; % 30; % seconds
        bolusChunkSec = 300; % 100; % seconds
        StO2rate = 1; % seconds
        fileChunkLength = 5*60; % seconds
        lslTimeoutDuration = 5; % seconds
        
        outfNameTmpl_raw = @(dateStr,nchn)sprintf('%s_%03d_raw.csv',...
                                                dateStr, nchn);
        outfNameTmpl_bol = @(dateStr,nchn)sprintf('%s_%03d_perfusion.csv',...
                                                dateStr, nchn);
        outfNameTmpl_oxy = @(dateStr,nchn)sprintf('%s_%03d_oxygenation.csv',...
                                                dateStr, nchn);

        CFGFILE = fullfile(fileparts(mfilename("fullpath")),'cfg.mat'); % config-file for app properties loaded at startup
        SAVEPROPS = {'selectedProject'}; % app properties to be saved in config-file
    end
    
    
    properties (Hidden)
        % mapping for status code to CSS ID in HTML template
        divIDs = {'neutral','confirm','warn','err'};
    end
    
    %% ========== METHODS =================================================
    
    methods % ----------------- GETTER & SETTER ---------------------------
        function pid = get.PID(app)
            pid = app.PIDEditField.Value;
        end
        function sid = get.SID(app)
            sid = app.SIDEditField.Value;
        end
        % . . . . . . . . . . . . . . . . . . . . . . . . . . . . . . . . .
        function active = get.capture_active(app)
            active = strcmpi(app.StreamDataSwitch.Value,'on');
        end
        function set.capture_active(app,val)
            validateattributes(val,{'logical','numeric'},{'scalar','nonnegative','integer'},1);
            if val
                app.StreamDataSwitch.Value = 'On';
            else
                app.StreamDataSwitch.Value = 'Off';
            end
        end
        % . . . . . . . . . . . . . . . . . . . . . . . . . . . . . . . . .
        function active = get.calc_StO2(app)
            active = strcmpi(app.RecordStO2CheckBox.Value,'on');
        end
        function set.calc_StO2(app,val)
            validateattributes(val,{'logical','numeric'},{'scalar','nonnegative','integer'},1);
            if val
                app.RecordStO2CheckBox.Value = true;
            else
                app.RecordStO2CheckBox.Value = false;
            end
        end

        % . . . . . . . . . . . . . . . . . . . . . . . . . . . . . . . . .

        function projNam = get.selectedProject(app)
            prjMenus = app.ProjectMenu.Children;
            iSel = find([prjMenus.Checked]);
            if isempty(iSel)
                projNam = '';
            else
                projNam = prjMenus(iSel(1)).Text;
            end
        end
        % . . . . . . . . . . . . . . . . . . . . . . . . . . . . . . . . .
        function set.selectedProject(app,val)
            prjMenus = app.ProjectMenu.Children;
            set(prjMenus,'Checked',0);
            kSel = find(strcmpi(val,{prjMenus.Text}),1,'first');
            if ~isempty(kSel)
                set(prjMenus(kSel),'Checked',true);
                pid = app.PIDEditField.Value;
                pid = regexprep(pid, '^([A-Z]*_)?', sprintf('%s_',prjMenus(kSel).Text), 'emptymatch');
                app.PIDEditField.Value = pid;
            else
                warning('Unknown project ''%s''!',val);
            end
        end


    end % -----------------------------------------------------------------


    methods (Access = public)
        function sts = lsl_init_input(app,varargin)
            % Opens the given lsl input streams (or all if no input
            % provided) and returns the status, which is >0 if there was an
            % error. If opening of any stream fails, the fields of the
            % requested streams in app.lsl_inlets are set to [].
            
            sts = 0;
            if nargin<2 % the object input for methods is counted as 1 input.
                strms = fieldnames(app.lsl_InlNames);
            else
                strms = varargin;
                validateFH = @(x)validatestring(x,fieldnames(app.lsl_InlNames),'lsl_init_input','strms');
                strms = cellfun(validateFH,strms,'UniformOutput',false);
            end
            for k = 1:numel(strms)
                name = app.lsl_InlNames.(strms{k});
                result = lsl_resolve_byprop(app.lsl_lib,'name', name, 1, 1);
                if ~isempty(result)
                    app.lsl_inlets.(strms{k}) = lsl_inlet(result{1});
                else
                    sts = 1;
                    break;
                end
            end
            if sts>0
                for k = 1:numel(strms)
                    app.lsl_inlets.(strms{k}) = [];
                end
            end
        end % -------------------------------------------------------------


        function capture_watchdog_timerInit(app,tObj,evnt)
            tObj.UserData{1} = struct(...
                'waitForInlet',        false,...
                'waitForData',         false);
        end % -------------------------------------------------------------
        
        
        function capture_watchdog_timerFnc_wrapper(app,tObj,evnt)
            tObj.UserData{3} = [];
            try
                app.capture_watchdog_timerFnc(tObj,evnt)
            catch ME
                tObj.UserData{3} = ME;
                rethrow(ME);
            end
        end % -------------------------------------------------------------
        
        function capture_watchdog_timerFnc(app,tObj,evnt)
            %
            if ~app.capture_active, tObj.stop; return; end
            if ~strcmpi(app.captureMeas_timer.Running,'off'), return; end
            
            % get lsl inlets ..........................................
            sts = app.lsl_init_input('nirs','trigger');
            if sts > 0
                if ~tObj.UserData{1}.waitForInlet
                    app.setStatus(0,'Waiting for Data & Trigger lsl inlet...');
                    tObj.UserData{1}.waitForInlet = true;
                end
                return;
            end
            tObj.UserData{1}.waitForInlet = false;
            app.lsl_init_input('acc');
%             app.setStatus(1,'Inlets detected.');
            
            % probe inlets for incoming data ..........................
            nProbe = 2;
            s_ = cell(nProbe,1); t_ = cell(nProbe,1);
            for k = 1:nProbe
                [s_{k}, t_{k}] = app.lsl_inlets.nirs.pull_sample(.5);
            end
            % NOTE: pull_sample returns a row vector (channels in columns)
            %       <-> pull_chunk returns channels in rows! 
            strmData = vertcat(s_{:});
            t = [t_{:}];
            if numel(t)<2
                if ~tObj.UserData{1}.waitForData
                    app.setStatus(0,'lsl inlets found, no data received.');
                    tObj.UserData{1}.waitForData = true;
                end
                return;
            end
            tObj.UserData{1}.waitForData = false;
            
            nchnLSL = size(strmData,2)-1;
            % try to infere start-time of measurement from frame count,
            % sampling rate ...........................................
            try 
                dt = app.lsl_inlets.nirs.info.nominal_srate;
                assert(~isempty(dt) && isnumeric(dt) && isscalar(dt) && dt>0, ...
                    'NSPstreamerGUI:capture_watchdog:lslSampleRateInvalid',...
                    'Sample rate from lsl stream meta data is invalid.');
                app.setStatus(1,sprintf('Incoming lsl stream: fs = %.6f, nChn = %d', dt, nchnLSL));
            catch ME
                dt = median(diff(t));
                msg = sprintf('Failed to get sampling frequency from lsl stream meta data. Reason:\n\t%s\n Estimating from timestamps: %.6f',...
                    ME.message, dt);
                app.setStatus(2,msg,'failedToRetrieveFS');
            end
            assert(~isempty(dt) && isnumeric(dt) && isscalar(dt) && dt>0, ...
                'NSPstreamerGUI:capture_watchdog:failedToGetSampleRate',...
                'Failed to get NIRS sample rate.');
            
            % find corresponding config.json...........................
            frmCnt = strmData(1,1);
            measRunTime = frmCnt*dt;
            startTime = now() - measRunTime/86400;
            % try to find matching measurement/config.json on disk. 
            dataDir = fullfile(app.PATHS.nspDataPth,char(datetime('now','Format','yyyy-MM-dd')));
            if ~exist(dataDir,'dir')
                dataDir = fullfile(app.PATHS.nspDataPth,char(datetime('yesterday','Format','yyyy-MM-dd')));
            end
            measDate = regexp(dataDir,'\d\d\d\d-\d\d-\d\d','match','once');
            measDirs = app.dir_filt(dataDir, sprintf('^%s_\\d\\d\\d$',measDate), 'dir');
            if isempty(measDirs)
                app.setStatus(2,'lsl data received, but data not found on disk.</br>If Aurora is not in preview mode, check app paths!');
                return;
            end
            measDirNamSort = sort({measDirs.name});
            recentMeasID = measDirNamSort{end};
            cfgFile = app.dir_filt(fullfile(dataDir,recentMeasID), sprintf('^%s_config\\.json$',recentMeasID), 'file');
            if isempty(cfgFile)
                app.setStatus(3,'lsl data received, but config.json not found on disk.</br>Data directory structure might be corrupted!');
                return;
            end
            if ~app.capture_active
                tObj.stop();
                return;
            end
            
            % compare estimated start time of measurement and
            % config.json file date
% TODO: check for files created LATER than start time,
% in case of preview measurement... can we check for preview in lsl
% meta data? No such info in stream info xml...
% https://biosignalsplux.com/downloads/apis/OpenSignals_Lab_Streaming_Layer_Guide_(MATLAB).pdf 
% CAVE: SOMETIMES FILES ARE WRITTEN DESPITE OF PREVIEW MODE !?! -> BUG
% REPORT?! Files are removed when preview is stopped?!
            dt = cfgFile.datenum - startTime; % >0 if file created after meas. start time
            % COMPLAIN if file was created more than 30 seconds BEFORE
            % measurement start:
            if dt < -30/86400 % abs(dt) > 30/86400
                msg = {'The datetime of the config-file doesn''t match the estimated starting time of the received lsl stream!' ...
                     sprintf('name: %s, created: %s',cfgFile.name, ...
                             datetime(cfgFile.datenum, 'ConvertFrom','datenum', 'Format','dd.MM.yy HH:mm:ss')) ...
                     'Do you want to use it anyway?'};
                figure(app.MainUIFigure); % bring figure up
                app.logger(msg);
                r = uiconfirm(app.MainUIFigure, ...
                              msg, ...
                              'config.json time stamp not matching', ...
                              'Icon','warning', 'Options',{'yes','retry','cancel'}, ...
                              'DefaultOption',2, 'CancelOption',3);
                app.logger(['->' r]);
                switch r
                    case 'yes'
                    case 'retry'
                        return;
                    case 'cancel'
                        app.capture_active = false;
                        tObj.stop();
                        return;
                    otherwise
                        app.capture_active = false;
                        tObj.stop();
                        error('Unhandled case.');
                end
            end
            app.logger(sprintf('Config file: %s (%s)', ...
                cfgFile.name, datetime(cfgFile.datenum, 'ConvertFrom','datenum', ...
                                       'Format','dd.MM.yy HH:mm:ss')));
            
            % check if number of channels in inlet and config match.
            cfgFileUri = fullfile(dataDir,recentMeasID,cfgFile.name);
            [nchnCfg, tag] = getCfgParam(cfgFileUri,'nchn','tag');
            if nchnCfg*4 ~= nchnLSL
                figure(app.MainUIFigure); % bring figure up
                msg = {'The number of channels in the most recent config.json file found does not match the number of channels in the received lsl stream!' ...
                     sprintf('%s: %d, lsl stream: %d', cfgFile.name, nchnCfg, nchnLSL/4) ...
                     };
                uialert(app.MainUIFigure, msg, 'Number of channel mismatch');
                app.logger(msg);
                app.capture_active = false;
                tObj.stop();
                return;
            end
            
            app.setStatus(1,'Config ok. Initialize measurement.');
            
            % start measurement capture (separate method, handling file
            % splitting, copying etc)
            app.captureMeas_timer.UserData{1} = cfgFileUri;
            try
                app.captureMeas_timer.start();
            catch ME
                app.IncomingdataLamp.Enable = 'off';
                app.StO2recordingLamp.Enable = 'off';
                rethrow(ME);
            end
            
        end % -------------------------------------------------------------


        function capture_watchdog_timerErr(app,tObj,evnt)
            app.capture_active = false;
            app.setStatus(3,'ERROR in capture watchdog timer!','captureWatchdogTimerFailed');
            figure(app.MainUIFigure); % bring figure up
            msg = {'There was an unexpected error while executing the capture watchdog timer.' ...
                  evnt.Data.message, ...
                 'See log/commandline for more details.' ...
                 };
            uialert(app.MainUIFigure, msg, 'Capture watchdog error');
            try
                msg = getReport(tObj.UserData{3},'extended','hyperlinks','off');
                disp(getReport(tObj.UserData{3},'extended','hyperlinks','on'));
            catch
                msg = '<no Exception available!>';
            end
            msg = sprintf('Capture watchdog timer error report: %s', msg);
            app.logger(msg);
        end % -------------------------------------------------------------

        function capture_watchdog_timerStop(app,tObj,evnt)
            app.setStatus(0,'Idle');
        end % -------------------------------------------------------------

        
        function captureMeas_timerInit(app,tObj,evnt)
            %
            
%             app.capture_watchdog_timer.stop();
            
            app.logger('Starting measurment initialization.')
            cfgFileUri = tObj.UserData{1};
%             timerVars = {'stO2_cfg', };
%             timerVars = [timerVars; cell(1,numel(timerVars))];
%             TD = struct(timerVars{:});
            tObj.UserData{3} = [];
            
            try
                [TD,succ] = app.prepMeas(cfgFileUri);
            catch ME
%                 succ = false;
%                 TD = [];
                tObj.UserData{3} = ME;
                app.setStatus(3,'Error while preparing measurement recording!')
                app.logger(sprintf('Error message: %s',ME.message));
                app.capture_active = false;
                rethrow(ME)
            end
            
            tObj.UserData{2} = TD;
            if ~succ
                app.capture_active = false;
                tObj.stop();
                app.setStatus(3,'Initialization of measurement capturing failed!')
            else
                app.IncomingdataLamp.Enable = 'on';
                if ~isempty(TD.stO2_cfg)
                    app.StO2recordingLamp.Enable = 'on';
                end
                app.setStatus(1,'Initialization ok. Start capturing.');
            end
            
        end % -------------------------------------------------------------

        
        function [TD,succ] = prepMeas(app,cfgFileUri)
            %
            TD = [];
            succ = false;
            % use config.json to setup StO2 config
            if app.calc_StO2
                try
                    stO2_cfg = stO2_getCFG(cfgFileUri); % define Sampling rate, Monitoring rate, NSP2 Channels assignment, Source/detector separations on probes, stream name, etc.
                    stO2_cfg.StO2rate = app.StO2rate; % Monitoring every second;
    
                catch ME
                    if startsWith(ME.identifier,'getStO2')
                        msg = {'Failed to get StO2 patch configuration!' ...
                             sprintf('config: %s', cfgFile.name) ...
                             sprintf('tag: %s', tag) ...
                             'StO2 recording will be disabled. Continue?'};
                        app.logger(msg);
                        r = uiconfirm(app.MainUIFigure, ...
                                      msg, ...
                                      'StO2 configuration error', ...
                                      'Icon','error', 'Options',{'yes','no'}, ...
                                      'DefaultOption',2, 'CancelOption',2);
                        app.logger(['->' r]);
                        switch r
                            case 'yes'
                            case 'no'
                                return;
                            otherwise
                                error('Unhandled case.');
                        end
                    else % throw unhandled errors
                        rethrow(ME);
                    end
                    app.calc_StO2 = false;
                    app.RecordStO2CheckBox.Enable = 'off';
                    stO2_cfg = [];
                end

            else % stO2 sreaming disabled
                stO2_cfg = [];
            end

            % read cfgHDR .................................................
            cfgHDRfile = regexprep(cfgFileUri,'json$','hdr','once');
            try cfgHDR = readConfigHDR(cfgHDRfile);
            catch ME
                app.setStatus(2,'Failed to read config-hdr file!','failedToReadCfgHdr');
                app.logger({sprintf('Failed to read config-hdr file %s because: ',cfgHDRfile), ...
                            ME.message});
            end
            
            % set local and remote path ...................................
            outPth_local = fullfile(app.PATHS.outPath_fallback, app.PID, app.SID);
            outPth_remot = fullfile(app.PATHS.outPath,          app.PID, app.SID);
            
            % create local path ...........................................
            if ~exist(outPth_local,'dir')
                [sts,errmsg] = mkdir(outPth_local);
                if sts == 0
                    figure(app.MainUIFigure); % bring figure up
                    msg = {'Failed to create local path!' ...
                           outPth_local ...
                           sprintf('Reason: %s',errmsg) ...
                           'Cannot save data. Please check PID, SID and filesystem permissions!'...
                           };
                    uialert(app.MainUIFigure, msg, 'File access error');
                    app.setStatus(3,msg{1},'failedToCreateLocalPath');
                    app.logger(msg);
                    return;
                end
            end
            
            % create and probe remote path ................................
            if ~exist(outPth_remot,'dir')
                [sts,errmsg] = mkdir(outPth_remot);
            else
                testfile = fullfile(outPth_remot,'tmp.file');
                [fid,errmsg] = fopen(testfile,'w');
                try
                   fclose(fid);
                   delete(testfile);
                end
                sts = fid~=-1;
            end
            if sts == 0 % remote not accessible, warn!
                figure(app.MainUIFigure); % bring figure up
                msg = {'Failed to access remote path!' ...
                       outPth_remot ...
                       sprintf('Reason: %s',errmsg) ...
                       'Sending files to remote will probably fail.'...
                       };
                app.setStatus(2,msg{1},'failedToAccessRemotePath');
                app.logger(msg);
                uialert(app.MainUIFigure, ...
                                  msg, ...
                                  'Remote path not available', ...
                                  'Icon','warning');
            end
            
            % get config info .............................................
            [nchn, fs, masterCfg, optim, chnMask, nAcc] = getCfgParam(cfgFileUri, ...
                'nchn','fs','mastercfg','optimized','channel_mask','nAccelerometer');
            [~,mstrCfgNam,~] = fileparts(masterCfg);

            % try to get aurora version ...................................
            try 
                aurora_vers = child_value(app.lsl_inlets.nirs.info.desc,'aurora_version');
                if isempty(aurora_vers) || ~(ischar(aurora_vers) || isstring(aurora_vers))
                    aurora_vers = cfgHDR.Version;
                end
                assert(~isempty(aurora_vers) && (ischar(aurora_vers) || isstring(aurora_vers)), ...
                    'NSPstreamerGUI:prepMeas:auroraVersInvalid',...
                    'Aurora version info is invalid.');
            catch ME
                aurora_vers = '';
                figure(app.MainUIFigure); % bring figure up
                msg = {'Failed to get Aurora version from lsl stream / hdr file.' ...
                       'Accelerometer data stream may be invalid!', 'Reason: ',...
                        ME.message};
                app.setStatus(2,msg{1},'failedToRetrieveAuroraVersion');
                app.logger(msg);
                uialert(app.MainUIFigure, ...
                                  msg, ...
                                  'Failed to get Aurora version', ...
                                  'Icon','error');
            end
            
            % get Acc stream ..............................................
            if nAcc>0
                if ~isempty(aurora_vers) && app.auroraVersionLessThan(aurora_vers,'2022.10')
                    msg = {'Accelerometer streaming is not supported with Aurora versions before 2022.10 because of incompatible time stamps.', ...
                           'Accelerometer data will not be streamed but has to be extracted offline.'};
                    app.setStatus(2,'Accelerometer data stream disabled','auroraVersIncompatibleForAccStreaming');
                    nAcc = 0;
                    app.lsl_inlets.acc = [];
                elseif isempty(app.lsl_inlets.acc)
                    sts = app.lsl_init_input('acc');
                    if sts > 0
                        msg = {'Configuration contains accelerometer but respective lsl streams could not be resolved.' ...
                               ' !! Accelerometer streaming DISABLED !!'};
                        app.setStatus(2,'Accelerometer data stream disabled','couldNotResolveAccStream');
                        nAcc = 0;
                    end
                end
                if nAcc==0
                    uialert(app.MainUIFigure, ...
                            msg, ...
                            'Accelerometer data stream disabled', ...
                            'Icon','warning');
                    app.logger(msg);
                end
            else, app.lsl_inlets.acc = [];
            end
            
            
            % provide layout files ........................................
            layoutBaseNam = regexprep(mstrCfgNam,'(_long)?(_\dstep)?(_sine|_rect)?(_fixedBrght)?(_laser.*)?(_calib)?$','');
            loFiles = dir(app.PATHS.optodeLayouts);
            loFileNames = {loFiles.name};
            lofPattern = sprintf('^(chn|opt)Pos_%s.csv$', regexptranslate('escape',layoutBaseNam));
            ilof = find(~cellfun(@isempty,regexp(loFileNames,lofPattern,'once')));
            if numel(ilof)~=2
                msg = sprintf('Unexpected number of optodeLayout-files found for setupID ''%s'': %d (need 2)', ...
                    layoutBaseNam, numel(ilof));
                app.logger(msg);
                uialert(app.MainUIFigure, ...
                        msg, ...
                        'Unexpected number of optodeLayout-files', ...
                        'Icon','warning');
            end
            for i = 1:numel(ilof)
                for dest = {outPth_local, outPth_remot}
                    [s,msg] = copyfile(fullfile(app.PATHS.optodeLayouts, loFileNames{ilof(i)}), ...
                             dest{1});
                    if s==0
                        wrnMsg = sprintf('Failed to copy optode layout file:\n ''%s'' -> ''%s''\nReason: %s',...
                            loFileNames{ilof(i)}, dest{1}, msg);
                        app.setStatus(2,wrnMsg,'failedToCopyLayouts');
                    end
                end
            end
            
            
            % provide source brightness of channels .......................
            % use time from hdr file to be replicable!
            try
                measDatetime = cfgHDR.Date;
            catch ME
                wrnMsg = sprintf('Failed to get date time from config.hdr because:\n%s\nUsing time of json file',ME.message);
                app.setStatus(2,wrnMsg,'failedToGetMeasDateTime');
                f = dir(cfgFileUri);
                measDatetime = datetime(f.datenum,'ConvertFrom','datenum');
            end
            srcBrgtnsFileName = sprintf('%s_chnSrcLevels.csv', ...
                                        datetime(measDatetime,'Format','yyyyMMdd-HHmmss'));
            srcBrgtnsFile_local = fullfile(outPth_local,srcBrgtnsFileName);
            try
                writeChnSrcBrghtns(cfgFileUri,srcBrgtnsFile_local);
                [s,msg] = copyfile(srcBrgtnsFile_local, outPth_remot);
                if s==0
                    wrnMsg = sprintf('Failed to copy channel source levels file:\n ''%s'' -> ''%s''\nReason: %s',...
                        srcBrgtnsFile_local, outPth_remot, msg);
                    app.setStatus(2,wrnMsg,'failedToChnSrcBrghtns');
                end
            catch ME
                wrnMsg = sprintf('Failed to write channel source levels because:\n%s',ME.message);
                app.setStatus(2,wrnMsg,'failedToWriteChnSrcBrghtns');
            end

            
            % prepare file headers. .......................................
            header_rawHb = mkHeader(chnMask,'rawHb',nAcc);
            if ~isempty(stO2_cfg)
                header_stO2 = mkHeader(stO2_cfg.patchOptodes,'StO2',nAcc);
            else
                header_stO2 = [];
            end
            
            % copy required data to structure for timer object ........
            TD = app.vars2struct(nchn, fs, masterCfg, optim, chnMask, nAcc, ...
                stO2_cfg, outPth_local, outPth_remot, header_rawHb, ...
                header_stO2, aurora_vers);
            
            % set t0 ......................................................
            TD.tOffset_uptime = (fix(now)-app.bootDatetime)*86400;
            % TD.tOffset_1970 = fix(now-datenum('1970-01-01 00:00','yyyy-mm-dd HH:MM'))*86400;
%             t0_uptime = now*86400-lsl_local_clock(app.lsl_lib); % rem(cfgIfo.datenum,1)*24*60*60;
            
            
            % initialize variable for measCapture timerFnc.............
            nirsFile = regexprep(cfgFileUri,'\.[^\.]+$','.nirs');
            TD.measStopped = @()exist(nirsFile,'file');
            TD.ticLSL = tic;
            TD.createNewFile = true;
            TD.bolus_running = 0;
            TD.preBolusBuffLen = ceil(app.bolusPreLength*fs)*2;
            TD.preBolusBuff = nan(nchn*4+3+nAcc*6,TD.preBolusBuffLen);
            TD.bolusTic = [];
            TD.bolusTrgCnt = [];
            TD.bolFile = {};
            TD.stO2Buff = [];
            TD.so2File = ''; 
            TD.accDbuffer = [];
            TD.accTbuffer = [];
            if ~isempty(stO2_cfg)
                TD.Width_Wd = ceil(fs/stO2_cfg.StO2rate);
            else
                TD.Width_Wd = NaN;
            end
            
            succ = true;
        end % prepMeas() --------------------------------------------------    
        
        
        function captureMeas_timerFnc_wrapper(app,tObj,evnt)
            tObj.UserData{3} = [];
            try
                app.captureMeas_timerFnc(tObj,evnt)
            catch ME
                tObj.UserData{3} = ME;
                rethrow(ME);
            end
        end % -------------------------------------------------------------

        function captureMeas_timerFnc(app,tObj,evnt)
            % . . . . . . . . . . . . . . . . . . . . . . . . . . . . . . .
            % stream data until button disabled or measurement stops (check
            % if .nirs file exists in data path and use timeout on incoming
            % lsl data in case aurora crashes w/o creating .nirs file.)

            TD = tObj.UserData{2};
            app.structfields2vars(TD);
            recStO2 = ~isempty(stO2_cfg);
            
            lslTimeout = toc(ticLSL) >= app.lslTimeoutDuration;
            if ~app.capture_active || measStopped() || lslTimeout
                tObj.stop();
                return;
            end
                
            % split files after app.fileChunkLength seconds, do not split bolus. Copy
            % files after split to remote. Use non-blocking system-script.
            [currentChunk, currentTStamp] = app.lsl_inlets.nirs.pull_chunk();
%             nirsTstmp = datetime;
            if isempty(currentChunk)
                return;
            end
            ndp = size(currentChunk,2);
            
            
            % pull and process trigger.....................................
            [trgChunk,trgTStamp] = app.lsl_inlets.trigger.pull_chunk();
            trgs = zeros(1,ndp);
            for ti = 1:numel(trgTStamp)
                dt = currentTStamp-trgTStamp(ti);
                if all(dt>0), mini = 1; % trigger came before first NIRS sample
                % else: assign trigger to the last sample that arrived
                % BEFORE trigger!
                else,         mini = find(dt<=0,1,'last');
                end
                trgs(mini) = trgChunk(2,ti);
            end
            
            
            % pull and process accelerometer ..............................
            % see accelerometer notes in Documents\NIRx_NIRSport2
            if nAcc>0
                [accChunk,accTStamp] = app.lsl_inlets.acc.pull_chunk();
                % TODO: time correction for older aurora versions?
                % accTStamp = accTStamp-tOffset_1970; % it's not that easy?!

                % the variables (12 rows) in the stream are: 
                % <DEVICE_NUM>,<ACC_NUM>,'acc_x','acc_y','acc_z','gyr_x','gyr_y','gyr_z','mag_x','mag_y','mag_z','hall_resistance'
                accDbuffer = [accDbuffer accChunk];
                accTbuffer = [accTbuffer accTStamp];
                accTic = tic;
                % fprintf('%d frames, %d acc samples, run ', ndp, numel(accTStamp)); % <<<<<<<<<<<< DEBUG/TESTING <<<<<<<<<<<<<<<<<<<<<<
                % nC=0; nPull=0; % <<<<<<<<<<<< DEBUG/TESTING <<<<<<<<<<<<<<<<<<<<<<
                % collect samples until we get to the time point of the
                % next (not yet arrived) nirs frame. Or we reach a timeout
                % of 3*sampling period.
                while (isempty(accChunk) || accTbuffer(end)<currentTStamp(end)+1/fs) ... 
                        && toc(accTic)<3/fs
                    pause(.1/fs); % wait 10% of sampling period
                    % nPull=nPull+1; % <<<<<<<<<<<< DEBUG/TESTING <<<<<<<<<<<<<<<<<<<<<<
                    [accChunk,accTStamp] = app.lsl_inlets.acc.pull_chunk();
                    accDbuffer = [accDbuffer accChunk];
                    accTbuffer = [accTbuffer accTStamp];
                    % fprintf(repmat('\b',1,nC)); % <<<<<<<<<<<< DEBUG/TESTING <<<<<<<<<<<<<<<<<<<<<<
                    % nC = fprintf('%d iterations',nPull); % <<<<<<<<<<<< DEBUG/TESTING <<<<<<<<<<<<<<<<<<<<<<
                end
                % if (isempty(accChunk) || accTbuffer(end)<currentTStamp(end)+1/fs), fprintf(' - TIMEOUT'); end  % <<<<<<<<<<<< DEBUG/TESTING <<<<<<<<<<<<<<<<<<<<<<
                % ttmp = toc(accTic); % <<<<<<<<<<<< DEBUG/TESTING <<<<<<<<<<<<<<<<<<<<<< 
                % fprintf('\n\tbuffer after %.2fs=%.2f-%.2f: %d ',ttmp, 3/fs, 3/fs-ttmp, numel(accTbuffer)); % <<<<<<<<<<<< DEBUG/TESTING <<<<<<<<<<<<<<<<<<<<<<
                accFrame = discretize(accTbuffer,[currentTStamp currentTStamp(end)+1/fs]);
                accDSmpl = nan(6*nAcc,ndp);

                for fi = 1:ndp
                    for ai = 1:nAcc
                        smplIdx = accFrame == fi & accDbuffer(2,:)==ai;
                        if ~any(smplIdx), continue; end
                        idx = (1:6) + (ai-1)*6;
                        % we currently only use acc and gyr:
                        accDSmpl(idx,fi) = max(accDbuffer(3:8,smplIdx), [], 2, "ComparisonMethod",'abs');
                    end
                end

                kKeep = find(~isnan(accFrame),1,'last')+1;
                accDbuffer = accDbuffer(:,kKeep:end);
                accTbuffer = accTbuffer(kKeep:end);
                % fprintf(' buffer after flush: %d \n',numel(accTbuffer)); % <<<<<<<<<<<< DEBUG/TESTING <<<<<<<<<<<<<<<<<<<<<<

                currentChunk = [currentChunk; accDSmpl];
            end
            
            
            % create new files if neccessary ..............................
            if createNewFile %#ok
%                 timeStmp = datestr(nirsTstmp - diff(seconds(currentTStamp([1 end]))),...
%                                    'yyyymmdd-HHMMSS');
                % more accurate:
                timeStmp = char(datetime(app.bootDatetime, ...
                                         'ConvertFrom','datenum', 'Format','yyyyMMdd-HHmmss') ...
                                + seconds(currentTStamp(1)));
                % timeStmp = datestr(seconds(currentTStamp(1)) + app.bootDatetime, ...
                %                    'yyyymmdd-HHMMSS');

                rawFile = fullfile(outPth_local,app.outfNameTmpl_raw(timeStmp,nchn));
                app.write2file(rawFile,header_rawHb);
                if recStO2
                    so2File = fullfile(outPth_local,app.outfNameTmpl_oxy(timeStmp,stO2_cfg.NPatch));
                    app.write2file(so2File,header_stO2);
                end
                
                createNewFile = false;
                samplCnt = 0;
            end
            
            
            % write data ..................................................
            currentDATA = [currentTStamp-tOffset_uptime; trgs; currentChunk];
            app.write2file(rawFile,currentDATA);
            samplCnt = samplCnt + ndp;
            
            
            % fill pre-Bolus-buff .........................................
            if ndp < preBolusBuffLen
                preBolusBuff = [preBolusBuff(:,ndp+1:end) currentDATA];
            else
                preBolusBuff = currentDATA;%(:,end-preBolusBuffLen+1:end);
            end
            
            
            % create files for bolus chunk ................................
            iBolus = ismember(currentDATA(2,:),app.bolusTrgNum(1));
            % TODO (minor): check for bolus abortion trigger?!
            if any(iBolus)
%                 timeStmp = datestr(nirsTstmp - diff(seconds(currentDATA(1,[find(iBolus,1,'first') end]))),...
%                                    'yyyymmdd-HHMMSS');
                % more accurate:
                bolusTStmp = currentTStamp(find(iBolus,1,'first'));
                % timeStmp = datestr(seconds(bolusTStmp) + app.bootDatetime, ...
                %                    'yyyymmdd-HHMMSS');
                timeStmp = char(datetime(app.bootDatetime,'ConvertFrom','datenum', ...
                                         'Format','yyyyMMdd-HHmmss') ...
                                + seconds(bolusTStmp));
                app.setStatus(1,sprintf('Bolus initiated (%s).', timeStmp));
                bolus_running = bolus_running + 1;
                bolusTic = [bolusTic tic];
                bolusTrgCnt = [bolusTrgCnt 2];

                % mk file, write header
                bolFile{end+1} = fullfile(outPth_local,app.outfNameTmpl_bol(timeStmp,nchn));
                app.write2file(bolFile{end},header_rawHb);
                
                % get pre-bolus-chunk from bolus buffer
                iBuff1 = size(preBolusBuff,2) - find(iBolus(end:-1:1),1,'first') + 1;
                iBuff0 = max(1, iBuff1 - preBolusBuffLen/2 + 1);
                preBolChnk = preBolusBuff(:,iBuff0:end);
                
                % remove previous trigger from pre-chunk
                preBolChnk(2,1:max(0,iBuff1-iBuff0)) = 0;

                % remove NaNs
                iBuff0 = find(~isnan(preBolChnk(1,:)),1,'first');
                preBolChnk = preBolChnk(:,iBuff0:end);
                preBolChnk(2,1) = app.bolusPreTrgNum;
                
                % write pre-bolus-chunk
                app.write2file(bolFile{end},preBolChnk);
                
                % reset bolus gauge
                app.BoluspassageGauge.Value = -app.bolusInitTime;
                app.BoluspassageGauge.Enable = 'on';
                app.BoluspassageGaugeLabel.Enable = 'on';

            elseif bolus_running>0 % write data to bolus file .............
                for smplIdx = 1:bolus_running
                    bolusData = currentDATA;
                    % keep only expected BolusTrigger numbers:
                    kRem = ismember(bolusData(2,:),app.bolusTrgNum);
                    bolusData(2,kRem) = 0;
                    for kTrg = bolusTrgCnt(smplIdx):numel(app.bolusTrgNum)
                        trgNum = app.bolusTrgNum(kTrg);
                        kKeep = find(currentDATA(2,:)==trgNum,1,'first');
                        if ~isempty(kKeep)
                            bolusTrgCnt(smplIdx) = bolusTrgCnt(smplIdx) + 1;
                            bolusData(2,kKeep) = trgNum;
                        else, break;
                        end
                    end

                    app.write2file(bolFile{smplIdx},bolusData);
                end
                app.BoluspassageGauge.Value = toc(bolusTic(end))-app.bolusInitTime;
            else
            end
            
            % truncate bolus buffer
            preBolusBuff = preBolusBuff(:,end-preBolusBuffLen+1:end);

            
            % calc StO2 ...................................................
            if recStO2
                stO2Buff = [stO2Buff currentDATA];
                for iBlk = 1:floor(size(stO2Buff,2)/Width_Wd)
                    bufIdx = (1:Width_Wd) + (iBlk-1)*Width_Wd;
                    bufOut = calc_OxStO2_new(sum(stO2Buff(3:end,bufIdx),2).', app.cnsts, stO2_cfg);
                    c = squeeze(bufOut.c).';
                    mua = bufOut.mua.';
                    writeOut = [stO2Buff(1:3,bufIdx(1)); ... frame; TStamp (first of StO2 chunk)
                                squeeze(bufOut.St).'; ... St
                                c(:); ... HbO; HbR
                                mua(:); ... mua WL1; WL2
                                ];
                    app.write2file(so2File,writeOut);
                end
                if ~isempty(iBlk) % remove processed data from buffer
                    stO2Buff = stO2Buff(:,(iBlk*Width_Wd)+1:end);
                end
            end
            
            
            % stop bolus recording? .......................................
            kRem = false(bolus_running,1);
            for smplIdx = 1:bolus_running
                if toc(bolusTic(smplIdx)) > app.bolusChunkSec+app.bolusInitTime
                    bolus_running = bolus_running-1;
                    app.setStatus(1, 'Recording bolus chunk stopped. Copy file to remote...');
                    app.copyFile(bolFile{smplIdx},outPth_remot);
                    kRem(smplIdx) = true;
                end
            end
            bolusTic(kRem) = [];
            bolFile(kRem) = [];
            bolusTrgCnt(kRem) = [];
            if bolus_running==0
                app.BoluspassageGauge.Enable = 'off';
                app.BoluspassageGaugeLabel.Enable = 'off';
            end

            
            % time for new files? .........................................
            if samplCnt/fs > app.fileChunkLength
                createNewFile = true;
                app.copyFile(rawFile,outPth_remot);
                if recStO2
                    app.copyFile(so2File,outPth_remot);
                end
            end
            
            
            % store data in timer object ..................................
            TD.ticLSL = tic;
            TD = app.vars2struct('--append',TD, ...
                createNewFile, rawFile, so2File, samplCnt, ...
                preBolusBuff, bolus_running, bolusTic, bolFile, bolusTrgCnt, ...
                accTbuffer, accDbuffer);
                
            tObj.UserData{2} = TD;
        end % -------------------------------------------------------------


        function captureMeas_timerErr(app,tObj,evnt)
            app.capture_active = false;
            tObj.stop(); % otherwise capturing might restart while uialert is displayed
            
            app.setStatus(3,'ERROR in captureMeas timer!','captureMeasTimerFailed');
            figure(app.MainUIFigure); % bring figure up
            msg = {'There was an unexpected error while executing the captureMeasurement timer.' ...
                  evnt.Data.message, ...
                 'See log/commandline for more details.' ...
                 };
            uialert(app.MainUIFigure, msg, 'Capture measurement error');
            try
                msg = getReport(tObj.UserData{3},'extended','hyperlinks','off');
                disp(getReport(tObj.UserData{3},'extended','hyperlinks','on'));
            catch
                msg = '<no Exception available!>';
            end
            msg = sprintf('Capture measurement timer error report: %s', msg);
            app.logger(msg);
        end % -------------------------------------------------------------


        function captureMeas_timerStop(app,tObj,evnt)
            %
            app.logger('Measurment capturing stopped.');
% TODO: TEST StO2 streaming: keep chckbox disabled for now:
%             app.RecordStO2CheckBox.Enable = 'on';
            app.IncomingdataLamp.Enable = 'off';
            app.StO2recordingLamp.Enable = 'off';
            % reset bolus gauge
            app.BoluspassageGauge.Value = -app.bolusInitTime;

            % restart watchdog if nesseccary
            if app.capture_active
%                 app.capture_watchdog_timer.start();
            end
            
            % copy last files to remote
            TD = tObj.UserData{2};
            for k = 1:TD.bolus_running
                app.copyFile(TD.bolFile{k}, TD.outPth_remot);
            end
            if ~TD.createNewFile
                app.copyFile(TD.rawFile, TD.outPth_remot);
                if ~isempty(TD.stO2_cfg)
                    app.copyFile(TD.so2File, TD.outPth_remot);
                end
            end
            
        end % -------------------------------------------------------------


        function write2file(app,file,data)
            % writes header if data ischar, otherwise assumes numeric data
            % and write with hard-coded format.
            % CONSIDER TO MAKE THIS STATIC?
            
            [fid,msg] = fopen(file,'a');
            if fid==-1
                error('NSPstreamerGUI:write2file:failedToOpenFile', ...
                    'Failed to open file ''%s'' for writing! Reason:\n %s',...
                    file, msg);
            end
            if ischar(data) % WRITE HEADER
                fprintf(fid,'%s',data);
            else % WRITE NUMERIC DATA
                ncol = size(data,1);
                frmt = ['\r\n%.3f,%d,%d' repmat(',%.8f',1,ncol-3)];
                fprintf(fid,frmt,data(:));
            end
            fclose(fid);
        end % -------------------------------------------------------------


        function copyFile(app,srcF,destP)
            %
            [srcP,srcN,srcE] = fileparts(srcF);
            srcN = [srcN srcE];
            
            logFile_ = sprintf('%s.log',tempname);
            
            try
                [sts,msg] = syscpFile(srcP,destP,srcN,logFile_);
            catch ME
                sts = 1;
                msg = ME.message;
            end
            if sts ~= 0
                app.setStatus(2,sprintf('Failed to run script that copies file (''%s'') to remote:\n%s',srcN,msg),...
                    'failedToRunCopyScript');
                return;
            end
            
            app.createLogWatchTimer(srcN,logFile_);
        end % -------------------------------------------------------------


        function createLogWatchTimer(app,srcN,logFile)
            %
            tName = sprintf('logWtchTimer_%s',srcN);
            tObj = timer(...
                    'ExecutionMode',    'fixedSpacing', ...
                    'Period',           5, ...
                    'StartDelay',       3, ...
                    'TasksToExecute',   20, ...
                    'Name',             tName, ...
                    'UserData',         {logFile,[],'',now}, ...
                    'StopFcn',          @(o,e)app.logWtchStopFnc(o,e), ...
                    'ErrorFcn',         @(o,e)app.logWtchErrFnc(o,e), ...
                    'TimerFcn',         @(o,e)app.logWtchTimerFnc(o,e) ...
                    );
            tObj.start();
        end % -------------------------------------------------------------


        function logWtchTimerFnc(app,tObj,evnt)
            %
            logFile = tObj.UserData{1};
            [sts,msg] = syscpGetStatus(logFile);
            tObj.UserData(2:3) = {sts,msg};
            switch sts
                case {-3 -2 0 3 4}
                    tObj.stop();
                case 2 % <<<<<< FOR TESTING, DISABLE IF STABLE: >>>>>>>>>>>
                    fprintf('%s: %s\n',tObj.Name, msg);
            end
        end % -------------------------------------------------------------


        function logWtchErrFnc(app,tObj,evnt)
            %
            fileNam = erase(tObj.Name,regexpPattern('^logWtchTimer_'));
            msg = sprintf('There was an error while probing the status of the copy process for file %s',fileNam);
            app.setStatus(2,msg,'logWatchTimerError');
            msg = sprintf('LogWatch timer error message: %s',evnt.Data.message);
            app.logger(msg);
        end % -------------------------------------------------------------


        function logWtchStopFnc(app,tObj,~)
            %
            fileNam = erase(tObj.Name,regexpPattern('^logWtchTimer_'));
            [logFile_,sts,msg,tStart] = tObj.UserData{:};
            if exist(logFile_,'file')
                try delete(logFile_); end
            end
            elapsedSec = (now()-tStart)*86400;
            switch sts
                case []
                    stsMsg = sprintf('LogWatch timer stopped without retrieved status for file ''%s''.',fileNam);
                    app.setStatus(2,stsMsg,'failedToGetStatus');
                case num2cell(-3:1)
                    stsMsg = sprintf('Unexpected error while copying file ''%s'' to remote.',fileNam);
                    app.setStatus(2,stsMsg,'copyScriptStoppedWithError');
                    logMsg = sprintf('LogWatch run for %.0f seconds and returned status: %s',elapsedSec,msg);
                    app.logger(logMsg);
                case 2
                    logMsg = sprintf('Copying of file ''%s'' seems to be still in progress after %.0f seconds: %s.\nStopping surveillance!',fileNam,elapsedSec,msg);
                    app.logger(logMsg);
                case 3
                    logMsg = sprintf('Successfully copied file ''%s'' to remote.',fileNam);
                    app.logger(logMsg);
                case 4
                    stsMsg = sprintf('Failed to copy file ''%s'' to remote. File not found?!',fileNam);
                    app.setStatus(2,stsMsg,'file2copyNotFound');
                case 99
                    stsMsg = sprintf('Failed to get status while copying file ''%s'' to remote.',fileNam);
                    app.setStatus(2,stsMsg,'copyScriptUnknownStatus');
                    logMsg = sprintf('LogWatch run for %.0f seconds and stopped with unknown status.',elapsedSec);
                    app.logger(logMsg);
                otherwise
                    app.setStatus(3,'Unhandled case for copy status. This is a bug.','copyStatusUnhandledCase');
            end
            tObj.delete();
        end % -------------------------------------------------------------


        function logger(app,msg)
            %
            if iscellstr(msg) || isstring(msg), msg = strjoin(msg,'\n'); end
            app.LogTextArea.Value = [{sprintf('%s: %s', datetime('now','Format','HH:mm:ss'), msg)}; ...
                app.LogTextArea.Value];
            
            app.write2file(app.logFile, ...
                sprintf('\n%s\t%s', ...
                        datetime('now','Format','yyyy-MM-dd_HH:mm:ss.SSS'), ...
                        msg));
%                         strrep(msg,newline,' ')));
        end % -------------------------------------------------------------


        function setStatus(app,level,msg,id)
            % level: 0: transparent, 1: green, 2: yellow, 3: red (see
            % status.html)
            divID = app.divIDs{level+1};
            app.StatusHTML.Data = sprintf('<div id="%s">%s</div>',divID,msg);
            drawnow();
            if exist('id','var')
                ST = dbstack(1);
                components = strsplit(ST(1).name,'.');
                wID = strjoin([components,{id}],':');
                warning(wID,'%s',msg);
            end
            app.logger(msg);
        end % -------------------------------------------------------------


        function selectProject(app)
            %
            app.PATHS = setPath(app.selectedProject);
        end % -------------------------------------------------------------


        function selProj = projectSelPopup(app)
            %
            projnams = {app.ProjectMenu.Children.Text};
            initVal = find(strcmp(projnams,app.selectedProject));
            if isempty(initVal), initVal = 1; end
            [selIdx,resp] = listdlg("ListString",projnams, "SelectionMode","single", ...
                "PromptString",'Select Project', 'InitialValue',initVal);
            if resp, selProj = projnams{selIdx};
            else,    selProj = '';
            end
        end % -------------------------------------------------------------

    end % methods public ==================================================


    methods (Static)
        function d = dir_filt(pth,filt,typ)
            % tpy: 'dir' 'file' or 'any' [default]
            if nargin < 3, typ = 'any'; end
            typ = validatestring(typ,{'dir','file','any'},3);
            d = dir(pth);
            if      strcmpi(typ,'dir'),  d = d( [d.isdir]);
            elseif  strcmpi(typ,'file'), d = d(~[d.isdir]);
            end
            idx = ~cellfun(@isempty,regexp({d.name},filt,'once'));
            d = d(idx);
        end % -------------------------------------------------------------
        
        
        function S = vars2struct(varargin)
            %
            if strcmp(varargin{1},'--append')
                assert(nargin>1 && isstruct(varargin{2}),'Variable to append to must be a structure!');
                S = varargin{2};
                offset = 3;
            else
                offset = 1;
            end
            for k = offset:nargin
                nam = inputname(k);
                assert(isvarname(nam),'var2struct:invalidInput',...
                    sprintf('Input %s at position %d does not evaluate to a valid variable name. (Indexing is not allowed!)',nam,k));
                S.(nam) = varargin{k};
            end
        end % -------------------------------------------------------------
        
        
        function structfields2vars(S)
            %
            nams = fieldnames(S);
            for k = 1:numel(nams)
                assignin('caller',nams{k},S.(nams{k}));
            end
        end % -------------------------------------------------------------


        function isLess = auroraVersionLessThan(versTestChar, versRefeChar)
            % isLess = auroraVersionLessThan(versTestChar, versRefeChar)
            %
            % EXAMPLE:
            % auroraVersionLessThan('2023.9.3-1-g01ca56fc','2023.9') % false
            % auroraVersionLessThan('2021.4.1-5-gd6b664d8','2023.9.3-1-g01ca56fc') % true
            % auroraVersionLessThan('2021.1','2021.1.3') % false
            
            vExpr = '\d+(?=[\.\-]|$)';
            versTest = regexp(versTestChar,vExpr,'match');
            versRefe = regexp(versRefeChar,vExpr,'match');

            assert(~isempty(versTest),' auroraVersionLessThan:invalidTestVersion',...
                'Failed to match version numbers from test-version (1st input)');
            assert(~isempty(versRefe), 'auroraVersionLessThan:invalidRefVersion',...
                'Failed to match version numbers from ref.-version (2nd input)');

            versTest = str2double(versTest);
            versRefe = str2double(versRefe);
            isLess = false;
            for k = 1:min(numel(versRefe),numel(versTest))
                if versTest(k)<versRefe(k)
                    isLess = true;
                    break;
                elseif versTest(k)>versRefe(k)
                    break;
                end
            end
        end
    end % methods static ==================================================


    %% ===================== v CALLBACKS v ================================

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            
% TODO: TEST StO2 streaming: disable checkbox for now:
            app.RecordStO2CheckBox.Enable = 'off';


            app.StatusHTML.HTMLSource = 'Initialize...';
            
            app.appPath = fileparts(fileparts(mfilename("fullpath")));
            addpath(app.appPath);
            
            logPth = fullfile(app.appPath,'Logs');
            if ~exist(logPth,'dir'), mkdir(logPth); end
            app.logFile = fullfile(logPth,sprintf('%s.log',datetime('now','Format','yyyy-MM-dd')));
            
            app.StatusHTML.HTMLSource = fullfile(fileparts(mfilename("fullpath")),'status.html');
            app.setStatus(0,'Initialize...');
            
            drawnow();
            addpath(fullfile(app.appPath,'util'));
            
            try 
                app.PATHS = setPath();
            catch ME
                app.setStatus(3,'FATAL: FAILED TO SET PATHS!');
                app.StreamDataSwitch.Enable = 'off';
                rethrow(ME);                
            end
            
            d = dir(app.PATHS.outPath);
            if isempty(d)
                figure(app.MainUIFigure); % bring figure up
                msg = {'Failed to access remote path!' ...
                       app.PATHS.outPath ...
                       'Check network connection.'...
                       };
                uialert(app.MainUIFigure,msg,'File destination not accessible',...
                    'Icon','warning');
            end
            
            try
                app.lsl_lib = lsl_loadlib();
            catch ME
                app.setStatus(3,'FATAL: FAILED TO LOAD LSL-LIB!');
                app.StreamDataSwitch.Enable = 'off';
                rethrow(ME);
            end
            
            app.BoluspassageGauge.Limits = [-app.bolusInitTime app.bolusChunkSec];
            x0 = fix(-app.bolusInitTime/10)*10;
            x1 = fix( app.bolusChunkSec/10)*10;
            if      x1-x0 < 10,  interv = 1;
            elseif  x1-x0 < 50,  interv = 5;
            elseif  x1-x0 < 100, interv = 10;
            elseif  x1-x0 < 200, interv = 20;
            elseif  x1-x0 < 500, interv = 50;
            else,                interv = 100;
            end
            app.BoluspassageGauge.MajorTicks = [x0:interv:x1];
            app.BoluspassageGauge.MajorTicks(end) = app.bolusChunkSec;
%             app.BoluspassageGauge.MajorTickLabels{end} = '';
app.BoluspassageGauge.MinorTicks = sort([0 x0:interv/2:x1]);
            app.BoluspassageGauge.Value = -app.bolusInitTime;

            app.SIDEditField.Value = datestr(fix(now*48)/48,'yyyymmdd-HHMMSS');
            
            app.cnsts = constants_init('AdultHead');
            app.bootDatetime = now-lsl_local_clock(app.lsl_lib)/86400;
            
            
            % create timer objects for capture watchdog & captureMeas .....
            app.capture_watchdog_timer = timer(app.capture_watchdog_timerProps{:},...
                'StartFcn', @(x,y)app.capture_watchdog_timerInit(x,y), ...
                'StopFcn',  @(x,y)app.capture_watchdog_timerStop(x,y), ...
                'TimerFcn', @(x,y)app.capture_watchdog_timerFnc_wrapper(x,y), ...
                'ErrorFcn', @(x,y)app.capture_watchdog_timerErr(x,y), ...
                'UserData', cell(3,1)); % {1}: vars-struct - {2}: unused? - {3}: MException
            
            app.captureMeas_timer = timer(app.captureMeas_timerProps{:},...
                'StartFcn', @(x,y)app.captureMeas_timerInit(x,y), ...
                'StopFcn',  @(x,y)app.captureMeas_timerStop(x,y), ...
                'TimerFcn', @(x,y)app.captureMeas_timerFnc_wrapper(x,y), ...
                'ErrorFcn', @(x,y)app.captureMeas_timerErr(x,y), ...
                'UserData', cell(3,1)); % {1}: cfgFileURI - {2}: Variables-struct - {3}: MException
            
            try
                cfg = load(app.CFGFILE);
                vars = fieldnames(cfg);
                for k = 1:numel(vars)
                    try
                        app.(vars{k}) = cfg.(vars{k});
                    catch ME
                        warning(ME.identifier,'Failed to assign config-var ''%s'' because\n%s',vars{k},ME.message);
                    end
                end
            catch ME
                warning(ME.identifier,'Failed to load config because\n%s',ME.message);
            end

            sp = projectSelPopup(app);
            if ~isempty(sp)
                app.selectedProject = sp;
            else
                % force get & set method (in case it has not been loaded from
                % config file)
                app.selectedProject = app.selectedProject;
            end
            app.selectProject();
            figure(app.MainUIFigure)

            app.setStatus(1,'Ready.');
            
        end

        % Close request function: MainUIFigure
        function MainUIFigureCloseRequest(app, event)
            try rmpath(app.appPath); end
            try rmpath(fullfile(app.appPath,'util')); end

            try delete(app.capture_watchdog_timer); end
            try delete(app.captureMeas_timer); end

            try
                for k = 1:numel(app.SAVEPROPS)
                    prop = app.SAVEPROPS{k};
                    cfg.(prop) = app.(prop);
                end
                save(app.CFGFILE,'-struct','cfg');
            catch ME
                warning(ME.identifier,'Failed to save config to disk because\n%s',ME.message);
            end

            delete(app)
            
        end

        % Value changed function: StreamDataSwitch
        function StreamDataSwitchValueChanged(app, event)
            if app.capture_active
                try
                    app.capture_watchdog_timer.start();
                catch ME
                    app.setStatus(3,sprintf('FATAL: FAILED TO START CAPTURE_WATCHDOG TIMER! sorry.</br><i>%s</i>', ...
                                            ME.message));
                    app.capture_active = false;
                    rethrow(ME);
                end
            else
                app.capture_watchdog_timer.stop();
                app.captureMeas_timer.stop();
            end
                        
        end

        % Menu selected function: CARLMenu, CAROMONMenu, ENABLEMenu, 
        % ...and 2 other components
        function ProjectSelectedCB(app, event)
            app.selectedProject = event.Source.Text;
            app.selectProject();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MainUIFigure = uifigure('Visible', 'off');
            app.MainUIFigure.Name = 'NSPstreamerMainUI';
            app.MainUIFigure.CloseRequestFcn = createCallbackFcn(app, @MainUIFigureCloseRequest, true);
            app.ProjectMenu = uimenu(app.MainUIFigure);
            app.ProjectMenu.Text = 'Project';
            app.ENABLEMenu = uimenu(app.ProjectMenu);
            app.ENABLEMenu.MenuSelectedFcn = createCallbackFcn(app, @ProjectSelectedCB, true);
            app.ENABLEMenu.Checked = 'on';
            app.ENABLEMenu.Text = 'ENABLE';
            app.YCORMenu = uimenu(app.ProjectMenu);
            app.YCORMenu.MenuSelectedFcn = createCallbackFcn(app, @ProjectSelectedCB, true);
            app.YCORMenu.Text = 'YCOR';
            app.STROKEMONMenu = uimenu(app.ProjectMenu);
            app.STROKEMONMenu.MenuSelectedFcn = createCallbackFcn(app, @ProjectSelectedCB, true);
            app.STROKEMONMenu.Text = 'STROKEMON';
            app.CAROMONMenu = uimenu(app.ProjectMenu);
            app.CAROMONMenu.MenuSelectedFcn = createCallbackFcn(app, @ProjectSelectedCB, true);
            app.CAROMONMenu.Text = 'CAROMON';
            app.CARLMenu = uimenu(app.ProjectMenu);
            app.CARLMenu.MenuSelectedFcn = createCallbackFcn(app, @ProjectSelectedCB, true);
            app.CARLMenu.Text = 'CARL';
            app.MeasurementInformationPanel = uipanel(app.MainUIFigure);
            app.MeasurementInformationPanel.Title = 'Measurement Information';
            app.PIDEditFieldLabel = uilabel(app.MeasurementInformationPanel);
            app.PIDEditFieldLabel.Text = 'PID';
            app.PIDEditField = uieditfield(app.MeasurementInformationPanel, 'text');
            app.PIDEditField.Tooltip = {'Enter Patient ID, PIZ# or Animal ID (e.g. ENABLE_003)'};
            app.SIDEditFieldLabel = uilabel(app.MeasurementInformationPanel);
            app.SIDEditFieldLabel.Text = 'SID';
            app.SIDEditField = uieditfield(app.MeasurementInformationPanel, 'text');
            app.SIDEditField.Tooltip = {'Enter Session ID: YYYYMMDD-HHMM00'};
            app.ControlsPanel = uipanel(app.MainUIFigure);
            app.ControlsPanel.Title = 'Controls';
            app.StreamDataSwitchLabel = uilabel(app.ControlsPanel);
            app.StreamDataSwitchLabel.Text = 'Stream Data';
            app.StreamDataSwitch = uiswitch(app.ControlsPanel, 'slider');
            app.StreamDataSwitch.ValueChangedFcn = createCallbackFcn(app, @StreamDataSwitchValueChanged, true);
            app.RecordStO2CheckBox = uicheckbox(app.ControlsPanel);
            app.RecordStO2CheckBox.Text = 'Record StO2';
            app.StatusPanel = uipanel(app.MainUIFigure);
            app.StatusPanel.Title = 'Status';
            app.IncomingdataLampLabel = uilabel(app.StatusPanel);
            app.IncomingdataLampLabel.Text = 'Incoming data';
            app.IncomingdataLamp = uilamp(app.StatusPanel);
            app.IncomingdataLamp.Enable = 'off';
            app.StO2recordingLampLabel = uilabel(app.StatusPanel);
            app.StO2recordingLampLabel.Text = 'StO2 recording';
            app.StO2recordingLamp = uilamp(app.StatusPanel);
            app.StO2recordingLamp.Enable = 'off';
            app.LogTextAreaLabel = uilabel(app.StatusPanel);
            app.LogTextAreaLabel.Text = 'Log';
            app.LogTextArea = uitextarea(app.StatusPanel);
            app.LogTextArea.Editable = 'off';
            app.StatusHTMLLabel = uilabel(app.StatusPanel);
            app.StatusHTMLLabel.Text = 'Status';
            app.StatusHTML = uihtml(app.StatusPanel);
            app.BoluspassageGaugeLabel = uilabel(app.StatusPanel);
            app.BoluspassageGaugeLabel.Enable = 'off';
            app.BoluspassageGaugeLabel.Text = 'Bolus passage';
            app.BoluspassageGauge = uigauge(app.StatusPanel, 'linear');
            app.BoluspassageGauge.Limits = [-10 100];
            app.BoluspassageGauge.MajorTicks = [-10 0 10 20 30 40 50 60 70 80 90 100];
            app.BoluspassageGauge.MinorTicks = [-10 -5 0 5 10 15 20 25 30 35 40 45 50 55 60 65 70 75 80 85 90 95 100];
            app.BoluspassageGauge.Enable = 'off';
            app.BoluspassageGauge.Value = -10;
            % Show the figure after all components are created
            app.MainUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NSPstreamerGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MainUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MainUIFigure)
        end
    end
end