classdef NSPstreamerGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MainUIFigure                 matlab.ui.Figure
        MeasurementInformationPanel  matlab.ui.container.Panel
        PIDEditFieldLabel            matlab.ui.control.Label
        PIDEditField                 matlab.ui.control.EditField
        SIDEditFieldLabel            matlab.ui.control.Label
        SIDEditField                 matlab.ui.control.EditField
        ControlsPanel                matlab.ui.container.Panel
        StreamDataSwitchLabel        matlab.ui.control.Label
        StreamDataSwitch             matlab.ui.control.Switch
        RecordStO2CheckBox           matlab.ui.control.CheckBox
        StatusPanel                  matlab.ui.container.Panel
        IncomingdataLampLabel        matlab.ui.control.Label
        IncomingdataLamp             matlab.ui.control.Lamp
        StO2recordingLampLabel       matlab.ui.control.Label
        StO2recordingLamp            matlab.ui.control.Lamp
        LogTextAreaLabel             matlab.ui.control.Label
        LogTextArea                  matlab.ui.control.TextArea
        StatusHTMLLabel              matlab.ui.control.Label
        StatusHTML                   matlab.ui.control.HTML
        BoluspassageGaugeLabel       matlab.ui.control.Label
        BoluspassageGauge            matlab.ui.control.LinearGauge
    end

    
    properties (Access = public)
        PATHS % structure of required paths
%         lsl_cfg % structure w/ data required for lsl
        cnsts % NIRS related constants
        lsl_inlets = struct('data',[],'trigger',[]);
        lsl_InlNames = struct('data','Aurora', 'trigger','NIRStarTriggers');
        
    end
    
    properties (Access = private)
        appPath % path to mlapp file
        lsl_lib % lsl library handle, setup at startup
    end
    
    properties (Dependent)
        PID % Patient ID
        SID % Session ID
        capture_active % true when Streaming switch in on
        calc_StO2 % true when StO2 checkbox is on
    end
    
    properties (Constant)
        bolusPreTrgNum = 48;
        bolusTrgNum = [49 50 51];
        bolusPreLength = 30; % seconds
        bolusChunkSec = 100; % seconds
        StO2rate = 1; % seconds
    end
    
    %% ========== METHODS =================================================
    
    methods % ----------- GETTER & SETTER -------------------
        function pid = get.PID(app)
            pid = app.PIDEditField.Value;
        end
        function sid = get.SID(app)
            sid = app.SIDEditField.Value;
        end
        
        function active = get.capture_active(app)
            active = app.StreamDataSwitch.Value;
        end
        function set.capture_active(app,val)
            validateattributes(val,{'logical','numeric'},{'scalar','nonnegative','integer'},1);
            app.StreamDataSwitch.Value = val;
        end
        
        function active = get.calc_StO2(app)
            active = app.RecordStO2CheckBox.Value;
        end
        function set.calc_StO2(app,val)
            validateattributes(val,{'logical','numeric'},{'scalar','nonnegative','integer'},1);
            app.RecordStO2CheckBox.Value = val;
        end
    end % -----------------------------------------------


    methods (Access = public)
        function sts = lsl_init_input(app)
            % TODO: can we check if a stream is still alive/available
            % before we reopen/overwrite it?
            % NO, but existing stream with same name is reuseable even if
            % aurora has been restarted! So no need to recreated it?!
            
            sts = 0;
            inl = fieldnames(app.lsl_InlNames);
            for k = 1:numel(inl)
                name = app.lsl_InlNames.(inl{k});
                result = lsl_resolve_byprop(app.lsl_lib,'name', name, 1, 1);
                if ~isempty(result)
                    app.lsl_inlets.(inl{k}) = lsl_inlet(result{1});
                else
                    sts = 1;
                    return;
                end
            end
        end % -------------------------------------------------------------
        
        
        function capture_watchdog(app)
            %
            while app.capture_active
                
                % get lsl inlets
                sts = app.lsl_init_input();
                if sts > 0
                    app.setStatus(0,'Data/Trigger lsl inlet not found.');
                    pause(1);
                    continue;
                end
                
                % probe inlets for incoming data
                nProbe = 2;
                s_ = cell(nProbe,1); t_ = cell(nProbe,1);
                for k = 1:nProbe
                    [s_{k}, t_{k}] = app.lsl_inlets.data.pull_sample(2);
                end
                strmData = [s_{:}];
                t = [t_{:}];
                if numel(t)<2
                    app.setStatus(0,'lsl inlets found, no data received.');
                    pause(1);
                    continue;                    
                end
                
                % try to infere start-time of measurement from frame count,
                % sampling rate
    % TODO: can we infere sample rate fomr lsl_inlet?, see lsl class
    % methods... or lsl meta data (aurora release notes say somthing about
    % subject info in lsl meta data.
    % fs: inlet.info.nominal_srate
                dt = median(diff(t));
                frmCnt = strmData(1,1);
                measRunTime = frmCnt*dt;
                startTime = now() - measRunTime/24/60/60;
                % try to find matching measurement/config.json on disk. 
                dataDir = fullfile(app.PATHS.nspDataPth,datestr(now(),'yyyy-mm-dd'));
                if ~exist(dataDir,'dir')
                    dataDir = fullfile(app.PATHS.nspDataPth,datestr(now()-1,'yyyy-mm-dd'));
                end
                measDate = regexp(dataDir,'\d\d\d\d-\d\d-\d\d','match','once');
                measDirs = app.dir_filt(dataDir, sprintf('^%s_\\d\\d\\d$',measDate), 'dir');
                if isempty(measDirs)
                    app.setStatus(2,'lsl data received, but data not found on disk.</br>If Aurora is not in preview mode, check app paths!');
                    continue;                    
                end
                measDirNamSort = sort({measDirs.name});
                recentMeasID = measDirNamSort{end};
                cfgFile = app.dir_filt(fullfile(dataDir,recentMeasID), sprintf('^%s_config\\.json$',recentMeasID), 'file');
                if isempty(cfgFile)
                    app.setStatus(3,'lsl data received, but config.json not found on disk.</br>Data directory structure might be corrupted!');
                    continue;                    
                end
                if ~app.capture_active, break; end
                
                % compare estimated start time of measurement and
                % config.json file date
    % TODO: check for files created LATER than start time,
    % in case of preview measurement... can we check for preview in lsl
    % meta data?
    % https://biosignalsplux.com/downloads/apis/OpenSignals_Lab_Streaming_Layer_Guide_(MATLAB).pdf 
                dt = cfgFile.datenum - startTime;
                if abs(dt) > 30 /24/60/60
                    figure(app.MainUIFigure); % bring figure up
                    r = uiconfirm(app.MainUIFigure, ...
                        {'The creation date of the most recent config.json file found does not match the estimated starting time of the received lsl stream!' ...
                         sprintf('name: %s, created: %s',cfgFile.name,datestr(cfgFile.datenum,'dd.mm.yy HH:MM:SS')) ...
                         'Do you want to use it anyway?'}, ...
                                  'config.json time stamp not matching', ...
                                  'Icon','warning', 'Options',{'yes','retry','cancel'}, ...
                                  'DefaultOption',2, 'CancelOption',3);
                    switch r
                        case 'yes'
                        case 'retry'
                            continue;
                        case 'cancel'
                            app.capture_active = false;
                            break;
                        otherwise
                            app.capture_active = false;
                            error('Unhandled case.');
                    end
                end
                
                % check if number of channels in inlet and config match.
                cfgFileUri = fullfile(dataDir,recentMeasID,cfgFile.name);
                [nchnCfg, tag] = getCfgParam(cfgFileUri,'nchn','tag');
                if nchnCfg*4 ~= size(strmData,1)-1
                    figure(app.MainUIFigure); % bring figure up
                    uialert(app.MainUIFigure, ...
                        {'The number of channels in the most recent config.json file found does not match the number of channels in the received lsl stream!' ...
                         sprintf('%s: %d, lsl stream: %d', cfgFile.name, nchnCfg, (size(strmData,1)-1)/4) ...
                         });
                    continue;
                end
                
                % use config.json to setup StO2 config
                try
                    stO2_cfg = stO2_getCFG(cfgFileUri); % define Sampling rate, Monitoring rate, NSP2 Channels assignment, Source/detector separations on probes, stream name, etc.
                    stO2_cfg.StO2rate = app.StO2rate; % Monitoring every second;

                catch ME
                    if ~app.calc_StO2
                       % ignore errors if stO2 disabled
                    elseif startsWith(ME.identifier,'getStO2')
                        r = uiconfirm(app.MainUIFigure, ...
                            {'Failed to get StO2 patch configuration!' ...
                             sprintf('config: %s', cfgFile.name) ...
                             sprintf('tag: %s', tag) ...
                             'StO2 recording will be disabled. Continue?'}, ...
                                      'StO2 configuration error', ...
                                      'Icon','error', 'Options',{'yes','no'}, ...
                                      'DefaultOption',2, 'CancelOption',2);
                        switch r
                            case 'yes'
                            case 'no'
                                app.capture_active = false;
                                break;
                            otherwise
                                app.capture_active = false;
                                error('Unhandled case.');
                        end
                    else % throw unhandled errors
                        rethrow(ME);
                    end
                    app.calc_StO2 = false;
                    app.RecordStO2CheckBox.Enable = 'off';
                    stO2_cfg = [];
                end
                
                % start measurement capture (separate method, handling file
                % splitting, copying etc)
                app.captureMeasurement(cfgFileUri,stO2_cfg)
                app.RecordStO2CheckBox.Enable = 'on';
            end
            
        end % -------------------------------------------------------------
        
        
        function captureMeasurement(app,cfgFileUri,stO2_cfg)
            %
            
            % set local and remote path, mkdir
            localPth = fullfile(app.PATHS.outPath_fallback, app.PID, app.SID);
            remotPth = fullfile(app.PATHS.outPath,          app.PID, app.SID);
            if ~exist(localPth,'dir'), mkdir(localPth); end
            if ~exist(remotPth,'dir'), mkdir(remotPth); end
            
            [nchn, masterCfg, optim, chnMask] = getCfgParam(cfgFileUri,'nchn','mastercfg','optimized','channel_mask');
            [~,mstrCfgNam,~] = fileparts(masterCfg);
            
            % provide layout files .................
            layoutBaseNam = regexprep(mstrCfgNam,'(_long)?(_sine|_rect)?$','');
            loFiles = dir(app.PATHS.optLayoutPath);
            loFileNames = {loFiles.name};
            lofPattern = sprintf('^(chn|opt)Pos_%s.csv$',layoutBaseNam);
            ilof = find(~cellfun(@isempty,regexp(loFileNames,lofPattern,'once')));
            if numel(ilof)~=2
                msg = sprintf('Unexpected number of optodeLayout-files found for setupID ''%s'' (%d), need 2!', ...
                    layoutBaseNam, numel(ilof));
                r = uiconfirm(app.MainUIFigure, ...
                        {msg ...
                         'Continue anyway?'}, ...
                              'Unexpected number of optodeLayout-files', ...
                              'Icon','warning', 'Options',{'yes','no'}, ...
                              'DefaultOption',2, 'CancelOption',2);
                switch r
                    case 'yes'
                    case 'no'
                        app.capture_active = false;
                        return;
                    otherwise
                        app.capture_active = false;
                        error('Unhandled case.');
                end
            end
            for i = 1:numel(ilof)
                for dest = {localPth, remotPth}
                    copyfile(fullfile(app.PATHS.optLayoutPath, loFileNames{ilof(i)}), ...
                             dest{1});
                end
            end
            
            % prepare file headers
            header_rawHb = mkHeader(chnMask,'rawHb');
            if ~isempty(stO2_cfg)
                header_stO2 = mkHeader(stO2_cfg.patchOptodes,'StO2');
            end
            
            % stream data until button disabled or measurement stops (check
            % if .nirs file exists in data path and use timeout on incoming
            % lsl data in case aurora crashes w/o creating .nirs file.) 
            measStopped = false;
            lslTimeout = false;
            while app.capture_active && ~measStopped && ~lslTimeout
                
                % split files after x seconds, do not split during bolus. Copy
                % files after split to remote. Use non-blocking system-script.
                
            end
            
        end % -------------------------------------------------------------
        
        
        function setStatus(app,level,msg)
            % level: 0: transparent, 1: green, 2: yellow, 3: red
            warning('TODO: setStatus method.\n%s',msg)
        end % -------------------------------------------------------------
    end
    
    
    methods (Static)
        function d = dir_filt(pth,filt,typ)
            % tpy: 'dir' 'file' or 'any' [default]
            if nargin < 3, typ = 'any'; end
            typ = validatestring(typ,{'dir','file','any'},3);
            d = dir(pth);
            if      strcmpi(typ,'dir'),  d = d( [d.isdir]);
            elseif  strcmpi(typ,'file'), d = d(~[d.isdir]);
            end
            idx = ~cellfun(@isempty,regexp({d.name},filt,'once'));
            d = d(idx);
        end
    end
    
    %% ======================= CALLBACKS ==================================

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.setStatus(0,'Initialize...');
            drawnow();
            app.appPath = fileparts(mfilename("fullpath"));
            addpath(app.appPath);
            addpath(fullfile(app.appPath,'util'));
            app.PATHS = setPath();
            
            try
                app.lsl_lib = lsl_loadlib();
            catch ME
                app.setStatus(3,'FATAL: FAILED TO LOAD LSL-LIB!');
                app.StreamDataSwitch.Enable = 'off';
                rethrow(ME);
            end
            
            
            app.cnsts = constants_init('AdultHead');
            app.setStatus(0,'Ready.');


        end

        % Close request function: MainUIFigure
        function MainUIFigureCloseRequest(app, event)
            try rmpath(app.appPath); end
            try rmpath(fullfile(app.appPath,'util')); end

            delete(app)
            
        end

        % Value changed function: StreamDataSwitch
        function StreamDataSwitchValueChanged(app, event)
            if app.StreamDataSwitch.Value
                try
                    app.capture_watchdog();
                    app.RecordStO2CheckBox.Enable = 'on';
                catch ME
                    app.setStatus(3,sprintf('FATAL: UNHANDLED EXCEPTION WHILE EXECUTING CAPTURE_WATCHDOG! sorry.</br>in <i>%s</i>, line %d', ...
                                            ME.stack(1).name, ME.stack(1).line));
                    app.capture_active = false;
                    app.RecordStO2CheckBox.Enable = 'on';
                    rethrow(ME);
                end
            end
                        
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MainUIFigure = uifigure('Visible', 'off');
            app.MainUIFigure.Name = 'NSPstreamerMainUI';
            app.MainUIFigure.CloseRequestFcn = createCallbackFcn(app, @MainUIFigureCloseRequest, true);
            app.MeasurementInformationPanel = uipanel(app.MainUIFigure);
            app.MeasurementInformationPanel.Title = 'Measurement Information';
            app.PIDEditFieldLabel = uilabel(app.MeasurementInformationPanel);
            app.PIDEditFieldLabel.Text = 'PID';
            app.PIDEditField = uieditfield(app.MeasurementInformationPanel, 'text');
            app.PIDEditField.Tooltip = {'Enter Patient ID, PIZ# or Animal ID (e.g. ENABLE_003)'};
            app.SIDEditFieldLabel = uilabel(app.MeasurementInformationPanel);
            app.SIDEditFieldLabel.Text = 'SID';
            app.SIDEditField = uieditfield(app.MeasurementInformationPanel, 'text');
            app.SIDEditField.Tooltip = {'Enter Session ID: YYYYMMSS-HHMM00'};
            app.ControlsPanel = uipanel(app.MainUIFigure);
            app.ControlsPanel.Title = 'Controls';
            app.StreamDataSwitchLabel = uilabel(app.ControlsPanel);
            app.StreamDataSwitchLabel.Text = 'Stream Data';
            app.StreamDataSwitch = uiswitch(app.ControlsPanel, 'slider');
            app.StreamDataSwitch.ValueChangedFcn = createCallbackFcn(app, @StreamDataSwitchValueChanged, true);
            app.RecordStO2CheckBox = uicheckbox(app.ControlsPanel);
            app.RecordStO2CheckBox.Text = 'Record StO2';
            app.StatusPanel = uipanel(app.MainUIFigure);
            app.StatusPanel.Title = 'Status';
            app.IncomingdataLampLabel = uilabel(app.StatusPanel);
            app.IncomingdataLampLabel.Text = 'Incoming data';
            app.IncomingdataLamp = uilamp(app.StatusPanel);
            app.IncomingdataLamp.Enable = 'off';
            app.StO2recordingLampLabel = uilabel(app.StatusPanel);
            app.StO2recordingLampLabel.Text = 'StO2 recording';
            app.StO2recordingLamp = uilamp(app.StatusPanel);
            app.StO2recordingLamp.Enable = 'off';
            app.LogTextAreaLabel = uilabel(app.StatusPanel);
            app.LogTextAreaLabel.Text = 'Log';
            app.LogTextArea = uitextarea(app.StatusPanel);
            app.LogTextArea.Editable = 'off';
            app.StatusHTMLLabel = uilabel(app.StatusPanel);
            app.StatusHTMLLabel.Text = 'Status';
            app.StatusHTML = uihtml(app.StatusPanel);
            app.StatusHTML.HTMLSource = '<body><span style="background-color:red;align-items: center;display: flex;">a</span></body>';
            app.BoluspassageGaugeLabel = uilabel(app.StatusPanel);
            app.BoluspassageGaugeLabel.Enable = 'off';
            app.BoluspassageGaugeLabel.Text = 'Bolus passage';
            app.BoluspassageGauge = uigauge(app.StatusPanel, 'linear');
            app.BoluspassageGauge.Limits = [-10 100];
            app.BoluspassageGauge.MajorTicks = [-10 0 10 20 30 40 50 60 70 80 90 100];
            app.BoluspassageGauge.MinorTicks = [-10 -5 0 5 10 15 20 25 30 35 40 45 50 55 60 65 70 75 80 85 90 95 100];
            app.BoluspassageGauge.Enable = 'off';
            app.BoluspassageGauge.Value = -10;
            % Show the figure after all components are created
            app.MainUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NSPstreamerGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MainUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MainUIFigure)
        end
    end
end